classdef appecg1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DiagnosticoTextArea       matlab.ui.control.TextArea
        DiagnosticoTextAreaLabel  matlab.ui.control.Label
        DuracinsEditField         matlab.ui.control.NumericEditField
        DuracinsEditFieldLabel    matlab.ui.control.Label
        CANALDropDown             matlab.ui.control.DropDown
        CANALDropDownLabel        matlab.ui.control.Label
        PROCESARECGButton         matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function PROCESARECGButtonPushed(app, event)

        end

        % Button pushed function: PROCESARECGButton
        function PROCESARECGButtonPushed2(app, event)
     
    % Cargar señal
    [signal, Fs, tm] = rdsamp('x0010');

    % Leer canal y duración elegidos
    channel = app.CANALDropDown.Value;
    duration = app.DuracinsEditField.Value;

    % Procesar ECG
    results = processECG(signal, Fs, tm, channel, duration);

    % Crear reporte
    report = {};
    report{end+1} = sprintf('FC: %.1f BPM', results.mean_heart_rate);
    report{end+1} = sprintf('SDNN: %.1f ms | RMSSD: %.1f ms', ...
                            results.sdnn, results.rmssd);
    report{end+1} = sprintf('Desv. ST: %.3f mV', results.mean_st_shift);
    report{end+1} = '-----------------------------------';

    if results.mean_heart_rate > 100
        report{end+1} = 'ALERTA: Taquicardia (>100 BPM)';
    elseif results.mean_heart_rate < 60
        report{end+1} = 'ALERTA: Bradicardia (<60 BPM)';
    else
        report{end+1} = 'Ritmo FC: Normal';
    end

    if results.sdnn > 100
        report{end+1} = 'ALERTA: Ritmo irregular';
    else
        report{end+1} = 'Regularidad RR: Estable';
    end

    if results.mean_st_shift > 0.10
        report{end+1} = 'CRITICO: Elevacion ST';
    elseif results.mean_st_shift < -0.10
        report{end+1} = 'ALERTA: Depresion ST';
    else
        report{end+1} = 'Segmento ST: Isoelectrico';
    end

    app.DiagnosticoTextArea.Value = report;

    % Gráfica PQRST
    cla(app.UIAxes);
    plot(app.UIAxes, results.time, results.ecg_filtered, ...
         'Color', [0.2 0.2 0.2]);
    hold(app.UIAxes, 'on');

    plot(app.UIAxes, results.time(results.locations), ...
         results.ecg_filtered(results.locations), ...
         'ro', 'MarkerFaceColor', 'r');

    plot(app.UIAxes, results.time(results.p_locs), ...
         results.ecg_filtered(results.p_locs), ...
         'bs', 'MarkerFaceColor', 'b');

    plot(app.UIAxes, results.time(results.q_locs), ...
         results.ecg_filtered(results.q_locs), ...
         'mv', 'MarkerFaceColor', 'm');

    plot(app.UIAxes, results.time(results.s_locs), ...
         results.ecg_filtered(results.s_locs), ...
         'k^', 'MarkerFaceColor', 'k');

    plot(app.UIAxes, results.time(results.t_locs), ...
         results.ecg_filtered(results.t_locs), ...
         'gd', 'MarkerFaceColor', 'g');

    hold(app.UIAxes, 'off');
    grid(app.UIAxes, 'on');
    title(app.UIAxes, sprintf('Delineacion PQRST - Canal %d', channel));
    xlabel(app.UIAxes, 'Tiempo (s)');
    ylabel(app.UIAxes, 'Amplitud (mV)');
    xlim(app.UIAxes, [0 min(10, duration)]);

    % Gráfica de frecuencia cardiaca
    cla(app.UIAxes2);
    plot(app.UIAxes2, results.heart_rate, 'o-', ...
         'Color', [0.85 0.25 0.2]);
    grid(app.UIAxes2, 'on');
    yline(app.UIAxes2, 60, '--k');
    yline(app.UIAxes2, 100, '--r');
    title(app.UIAxes2, 'FC Instantanea');
    xlabel(app.UIAxes2, 'Numero de latido');
    ylabel(app.UIAxes2, 'BPM');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.PROCESARECGButton = uibutton(app.UIFigure, 'push');
            app.PROCESARECGButton.ButtonPushedFcn = createCallbackFcn(app, @PROCESARECGButtonPushed2, true);
            app.PROCESARECGButton.Text = 'PROCESAR ECG';
            app.CANALDropDownLabel = uilabel(app.UIFigure);
            app.CANALDropDownLabel.Text = 'CANAL';
            app.CANALDropDown = uidropdown(app.UIFigure);
            app.CANALDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.CANALDropDown.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12];
            app.CANALDropDown.Value = 1;
            app.DuracinsEditFieldLabel = uilabel(app.UIFigure);
            app.DuracinsEditFieldLabel.Text = 'Duración(s)';
            app.DuracinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DuracinsEditField.Value = 60;
            app.DiagnosticoTextAreaLabel = uilabel(app.UIFigure);
            app.DiagnosticoTextAreaLabel.Text = 'Diagnostico';
            app.DiagnosticoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appecg1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end